classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        LeftKnobLabel          matlab.ui.control.Label
        LeftKnob               matlab.ui.control.Knob
        RightKnobLabel         matlab.ui.control.Label
        RightKnob              matlab.ui.control.Knob
        UIAxes                 matlab.ui.control.UIAxes
        Light_XEditFieldLabel  matlab.ui.control.Label
        Light_XEditField       matlab.ui.control.NumericEditField
        Light_YEditFieldLabel  matlab.ui.control.Label
        Light_YEditField       matlab.ui.control.NumericEditField
        UpdateButton           matlab.ui.control.Button
        X0EditFieldLabel       matlab.ui.control.Label
        X0EditField            matlab.ui.control.NumericEditField
        Y0EditFieldLabel       matlab.ui.control.Label
        Y0EditField            matlab.ui.control.NumericEditField
        Theta0EditFieldLabel   matlab.ui.control.Label
        Theta0EditField        matlab.ui.control.NumericEditField
        ANNSwitchLabel         matlab.ui.control.Label
        ANNSwitch              matlab.ui.control.ToggleSwitch
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: UpdateButton
        function UpdateButtonPushed(app, event)
        % Create UIAxes
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Map')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            axis(app.UIAxes, [0 200 0 100 0 1])
            app.UIAxes.PlotBoxAspectRatio = [1.77450980392157 1 1];
            app.UIAxes.TickLength = [0.01 0.01];
            app.UIAxes.Position = [27 1 592 362];
            light_x = app.Light_XEditField.Value;
            light_y = app.Light_YEditField.Value;
            
            %robot pose
            x0 = app.X0EditField.Value + 12.5;
            y0 = app.Y0EditField.Value + 12.5;
            theta0 = app.Theta0EditField.Value;
            
            l = 15;
            Rwheel = 2.5;
            
            g = hgtransform(app.UIAxes);
            
            theta_cur = theta0;
            x_cur = x0;
            y_cur = y0;
            x0sl = x_cur-x0+10;
            y0sl = y_cur-y0+7.5;
            x0sr = x_cur-x0+10;
            y0sr = y_cur-y0-7.5;  


            %robot body
            d = 12.5;
            tf(1) = rectangle(app.UIAxes, 'Position', [2.5-12.5, 5-12.5,  20, 15], "Curvature", 0.2, "EdgeColor", 'r', 'Parent', g);         
            tf(2) = rectangle(app.UIAxes, 'Position', [20-12.5, 0-12.5, 5, 10], 'Curvature', [1 1], "LineStyle","--", 'Parent', g);
            tf(3) = rectangle(app.UIAxes, 'Position', [20-12.5, 15-12.5, 5, 10], 'Curvature', [1 1], "LineStyle","--", 'Parent', g);
            plot(app.UIAxes, x0sl, y0sl, "Marker", "x", "MarkerSize", 10, "LineWidth",3, 'Color', 'r', 'Parent', g);
            plot(app.UIAxes, x0sr, y0sr, "Marker", "x", "MarkerSize", 10, "LineWidth",3, 'Color', 'r', 'Parent', g);
            
%                 g.Matrix = makehgtform('translate', [12.5 12.5 0]);
            
            g.Matrix = makehgtform('zrotate', theta0);
            g.Matrix = makehgtform('translate', [(x0), (y0), 0]);
            
            while (((sqrt((light_x - x0sl)^2 + (light_y - y0sl)^2)) > 15) &&...
                        ((sqrt((light_x - x0sr)^2 + (light_y - y0sr)^2)) > 15))

            t = 0.5;
                
               if (app.ANNSwitch.Value == "Off")
                    w_l = app.LeftKnob.Value;
                    w_r = app.RightKnob.Value;
                
                
                    v_l = w_l * Rwheel;
                    v_r = w_r * Rwheel;
                end

                V = (v_r + v_l)/2;
            
                R = ((v_r + v_l)/(v_r - v_l))*l/2;
            
                W = (v_r - v_l)/l;
                ICC_x = x_cur - R*sind(theta_cur);
                ICC_y = y_cur + R*cosd(theta_cur);
                
                 
%                 x_cur_ik = (l/2)*((v_r + v_l)/(v_r - v_l))*sin((t/l)*(v_r - v_l))
%                 y_cur_ik = -(l/2)*((v_r + v_l)/(v_r - v_l))*cos((t/l)*(v_r - v_l))
%                 theta_cur_ik = (t/l)*(v_r - v_l)
                
%                 x_cur = (-R*sin(theta_cur)+R*sin(theta_cur+W*t)) + x0;
%                 y_cur = (R*cos(theta_cur)-R*cos(theta_cur+W*t)) + y0;
%                 if R == Inf
                x_cur = (V*cos(theta_cur))*t+x_cur;
                y_cur = (V*sin(theta_cur))*t+y_cur;
                x0sl = (V*cos(theta_cur))*t+x0sl;
                y0sl = (V*sin(theta_cur))*t+y0sl;
                x0sr = (V*cos(theta_cur))*t+x0sr;
                y0sr = (V*sin(theta_cur))*t+y0sr;
%                 else
%                     x_cur = (cosd(W*t))*(x_cur - ICC_x) - (sind(W*t))*(y_cur-ICC_y) + ICC_x;
%                     y_cur = (sind(W*t))*(x_cur - ICC_x) + (cosd(W*t))*(x_cur-ICC_y) + ICC_y;
                theta_cur = (W*t) + theta_cur;
%                 end

            
                hold(app.UIAxes,'on')
                
                plot(app.UIAxes, light_x, light_y, "Marker", "*", "MarkerSize", 51, "LineWidth",3, 'Color', 'yellow');
                
                rectangle(app.UIAxes, 'Position', [35, 75, 20, 20], 'Curvature', [1 1]);
                rectangle(app.UIAxes, 'Position', [40, 35, 20, 20], 'Curvature', [1 1]);
                rectangle(app.UIAxes, 'Position', [145, 40, 20, 20], 'Curvature', [1 1]);
                rectangle(app.UIAxes, 'Position', [175, 5, 20, 20], 'Curvature', [1 1]);


    %             set(t, 'Parent', g);
                
    %             rot0 = makehgtform('axisrotate',[0, 0,  sqrt((12.5+x0)^2 + (12.5+y0)^2)], theta0*pi/180);
    %             g.Matrix = makehgtform('zrotate', theta0*pi/180);
                g.Matrix = makehgtform('translate', [x_cur, y_cur, 0], 'zrotate', theta_cur);
    %             g.Matrix = makehgtform('axisrotate',[0, 0, 1], theta0*pi/180);


                hold on
                plot(app.UIAxes, [0 0], [0 100], "LineWidth", 5,"Color", 'b');
                hold off
                
                hold on
                plot(app.UIAxes, [200 200], [0 100], "LineWidth", 5,"Color", 'b');
                hold off
                
                hold on
                plot(app.UIAxes, [0 200], [100 100], "LineWidth", 5,"Color", 'b');
                hold off
                
                hold on
                plot(app.UIAxes, [0 200], [0 0], "LineWidth", 5,"Color", 'b');
                hold off
                hold(app.UIAxes,'off')

   
%             img = imread('ddr.png','parent');
%             img = ~imbinarize(img);
%             image(app.UIAxes, img,'XData', [0.6 0.7], 'YData', [0.7 0.9]);
%             imshow('ddr.png','Parent', app.UIAxes);
            pause(1/24)
            end
        end

        % Value changed function: ANNSwitch
        function ANNSwitchValueChanged(app, event)
            value = app.ANNSwitch.Value;
        % Create UIAxes
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Map')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            axis(app.UIAxes, [0 200 0 100 0 1])
            app.UIAxes.PlotBoxAspectRatio = [1.77450980392157 1 1];
            app.UIAxes.TickLength = [0.01 0.01];
            app.UIAxes.Position = [27 1 592 362];
            light_x = app.Light_XEditField.Value;
            light_y = app.Light_YEditField.Value;
            
            %robot pose
            x0 = app.X0EditField.Value + 12.5;
            y0 = app.Y0EditField.Value + 12.5;
            theta0 = app.Theta0EditField.Value;
            
            l = 15;
            Rwheel = 2.5;
            
            g = hgtransform(app.UIAxes);
            
            theta_cur = theta0;
            x_cur = x0;
            y_cur = y0;
            x0sl = x_cur-x0+10;
            y0sl = y_cur-y0+7.5;
            x0sr = x_cur-x0+10;
            y0sr = y_cur-y0-7.5;
            cl = 0;
            cr = 0;


            %robot body
            d = 12.5;
            tf(1) = rectangle(app.UIAxes, 'Position', [2.5-12.5, 5-12.5,  20, 15], "Curvature", 0.2, "EdgeColor", 'r', 'Parent', g);         
            tf(2) = rectangle(app.UIAxes, 'Position', [20-12.5, 0-12.5, 5, 10], 'Curvature', [1 1], "LineStyle","--", 'Parent', g);
            tf(3) = rectangle(app.UIAxes, 'Position', [20-12.5, 15-12.5, 5, 10], 'Curvature', [1 1], "LineStyle","--", 'Parent', g);
            plot(app.UIAxes, x0sl, y0sl, "Marker", "x", "MarkerSize", 10, "LineWidth",3, 'Color', 'r', 'Parent', g);
            plot(app.UIAxes, x0sr, y0sr, "Marker", "x", "MarkerSize", 10, "LineWidth",3, 'Color', 'r', 'Parent', g);
            
%                 g.Matrix = makehgtform('translate', [12.5 12.5 0]);
            
            g.Matrix = makehgtform('zrotate', theta0);
            x0sl_new = (cosd(theta0)*x0sl - sind(theta0)*y0sl);
            y0sl_new = (sind(theta0)*x0sl + cosd(theta0)*y0sl);
            x0sr_new = (cosd(theta0)*x0sr - sind(theta0)*y0sr);
            y0sr_new = (sind(theta0)*x0sr + cosd(theta0)*y0sr);
            plot(app.UIAxes, x0sl_new, y0sl_new, "Marker", ".", "MarkerSize", 10, "LineWidth",3, 'Color', 'g', 'Parent', g);
            plot(app.UIAxes, x0sr_new, y0sr_new, "Marker", ".", "MarkerSize", 10, "LineWidth",3, 'Color', 'g', 'Parent', g);
            g.Matrix = makehgtform('translate', [(x0), (y0), 0]);
            x0sl_new = x0sl_new + x0-12.5;
            y0sl_new = y0sl_new + y0-12.5;
            x0sr_new = x0sr_new + x0-12.5;
            y0sr_new = y0sr_new + y0-12.5;
            plot(app.UIAxes, x0sl_new, y0sl_new, "Marker", ".", "MarkerSize", 10, "LineWidth",3, 'Color', 'b', 'Parent', g);
            plot(app.UIAxes, x0sr_new, y0sr_new, "Marker", ".", "MarkerSize", 10, "LineWidth",3, 'Color', 'b', 'Parent', g);
            
            while (sqrt((light_x - (x0sl_new + x0sr_new)/2)^2+(light_y - (y0sl_new + y0sr_new)/2)^2) > 20)
                    
    
                t = 0.5;
            
         
                %ANN declaration
                dls_l = (sqrt((light_x - x0sl_new)^2 + (light_y - y0sl_new)^2));
                dls_r = (sqrt((light_x - x0sr_new)^2 + (light_y - y0sr_new)^2));


                %Collision borders
                if (x0sl_new < 3 || x0sl_new > 195) 
                    cl = abs(x0sl_new-100)/100;
                    cr = 0;
                elseif (x0sr_new < 3 || x0sr_new > 195)
                    cr = abs(x0sr_new-100)/100;
                    cl = 0;
                elseif ((x0sl_new < 3 && x0sr_new < 5) || (x0sl_new > 195 && x0sr_new > 195))
                    cl = -abs(x0sl_new-100)/100;
                    cr = -abs(x0sr_new-100)/100;
                elseif (y0sl_new < 3 || y0sl_new > 195) 
                    cl = abs(y0sl_new-100)/100;
                    cr = 0;
                elseif (y0sr_new < 3 || y0sr_new > 195)
                    cr = abs(y0sr_new-100)/100;
                    cl = 0;
                elseif ((y0sl_new < 3 && y0sr_new < 3) || (y0sl_new > 195 && y0sr_new > 195))
                    cl = -abs(y0sl_new-100)/100;
                    cr = -abs(y0sr_new-100)/100;
                elseif (((x0sl_new < 3 && x0sr_new < 3) || (x0sl_new > 195 && x0sr_new > 195)) &&...
                        ((y0sl_new < 3 && y0sr_new < 3) || (y0sl_new > 195 && y0sr_new > 195)))
                    cl = -abs(y0sl_new-100)/100;
                    cr = -abs(y0sr_new-100)/100;
                else 
                    cr = 0;
                    cl = 0;
                end
                dls_r = dls_l + 0
                w_l = 5000*((dls_l)/(dls_r+dls_l)^2);
                w_r = 5000*((dls_r)/(dls_l+dls_r)^2);
            
            
                v_l = w_l * Rwheel;
                v_r = w_r * Rwheel;
            

                V = (v_r + v_l)/2;
            
                R = ((v_r + v_l)/(v_r - v_l))*l/2;
            
                W = (v_r - v_l)/l;
                ICC_x = x_cur - R*sind(theta_cur);
                ICC_y = y_cur + R*cosd(theta_cur);
                
                 
%                 x_cur_ik = (l/2)*((v_r + v_l)/(v_r - v_l))*sin((t/l)*(v_r - v_l))
%                 y_cur_ik = -(l/2)*((v_r + v_l)/(v_r - v_l))*cos((t/l)*(v_r - v_l))
%                 theta_cur_ik = (t/l)*(v_r - v_l)
                
%                 x_cur = (-R*sin(theta_cur)+R*sin(theta_cur+W*t)) + x0;
%                 y_cur = (R*cos(theta_cur)-R*cos(theta_cur+W*t)) + y0;
%                 if R == Inf
                x_cur1 = x_cur;
                y_cur1 = y_cur;
                x_cur = (V*cos(theta_cur))*t+x_cur;
                y_cur = (V*sin(theta_cur))*t+y_cur;
                x_cur2 = x_cur;
                y_cur2 = y_cur;
%                 x0sl = (V*cos(theta_cur))*t+x0sl;
%                 y0sl = (V*sin(theta_cur))*t+y0sl;
%                 x0sr = (V*cos(theta_cur))*t+x0sr;
%                 y0sr = (V*sin(theta_cur))*t+y0sr;

%                 else
%                     x_cur = (cosd(W*t))*(x_cur - ICC_x) - (sind(W*t))*(y_cur-ICC_y) + ICC_x;
%                     y_cur = (sind(W*t))*(x_cur - ICC_x) + (cosd(W*t))*(x_cur-ICC_y) + ICC_y;
                theta_cur = (W*t) + theta_cur;
%                 end

            
                hold(app.UIAxes,'on')
                
                plot(app.UIAxes, light_x, light_y, "Marker", "*", "MarkerSize", 51, "LineWidth",3, 'Color', 'yellow');
                
                rectangle(app.UIAxes, 'Position', [35, 75, 20, 20], 'Curvature', [1 1]);
                rectangle(app.UIAxes, 'Position', [40, 35, 20, 20], 'Curvature', [1 1]);
                rectangle(app.UIAxes, 'Position', [145, 40, 20, 20], 'Curvature', [1 1]);
                rectangle(app.UIAxes, 'Position', [175, 5, 20, 20], 'Curvature', [1 1]);


    %             set(t, 'Parent', g);
                
    %             rot0 = makehgtform('axisrotate',[0, 0,  sqrt((12.5+x0)^2 + (12.5+y0)^2)], theta0*pi/180);
    %             g.Matrix = makehgtform('zrotate', theta0*pi/180);
                g.Matrix = makehgtform('translate', [x_cur, y_cur, 0], 'zrotate', theta_cur);
                x0sl_new = (cosd(theta_cur)*x0sl_new - sind(theta_cur)*y0sl_new);
                y0sl_new = (sind(theta_cur)*x0sl_new + cosd(theta_cur)*y0sl_new);
                x0sr_new = (cosd(theta_cur)*x0sr_new - sind(theta_cur)*y0sr_new);
                y0sr_new = (sind(theta_cur)*x0sr_new + cosd(theta_cur)*y0sr_new);
                plot(app.UIAxes, x0sl_new, y0sl_new, "Marker", ".", "MarkerSize", 10, "LineWidth",3, 'Color', 'y', 'Parent', g);
                plot(app.UIAxes, x0sr_new, y0sr_new, "Marker", ".", "MarkerSize", 10, "LineWidth",3, 'Color', 'y', 'Parent', g);
                
                x0sl_new = x0sl_new + (x_cur2-x_cur1);
                y0sl_new = y0sl_new + (y_cur2-y_cur1);
                x0sr_new = x0sr_new + (x_cur2-x_cur1);
                y0sr_new = y0sr_new + (y_cur2-y_cur1);
                plot(app.UIAxes, x0sl_new, y0sl_new, "Marker", ".", "MarkerSize", 10, "LineWidth",3, 'Color', 'r', 'Parent', g);
                plot(app.UIAxes, x0sr_new, y0sr_new, "Marker", ".", "MarkerSize", 10, "LineWidth",3, 'Color', 'r', 'Parent', g);
         
    %             g.Matrix = makehgtform('axisrotate',[0, 0, 1], theta0*pi/180);


                hold on
                plot(app.UIAxes, [0 0], [0 100], "LineWidth", 5,"Color", 'b');
                hold off
                
                hold on
                plot(app.UIAxes, [200 200], [0 100], "LineWidth", 5,"Color", 'b');
                hold off
                
                hold on
                plot(app.UIAxes, [0 200], [100 100], "LineWidth", 5,"Color", 'b');
                hold off
                
                hold on
                plot(app.UIAxes, [0 200], [0 0], "LineWidth", 5,"Color", 'b');
                hold off
                hold(app.UIAxes,'off')

   
%             img = imread('ddr.png','parent');
%             img = ~imbinarize(img);
%             image(app.UIAxes, img,'XData', [0.6 0.7], 'YData', [0.7 0.9]);
%             imshow('ddr.png','Parent', app.UIAxes);
            pause(1/10)
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.LeftKnobLabel = uilabel(app.UIFigure);
            app.LeftKnobLabel.Text = 'Left';
            app.LeftKnob = uiknob(app.UIFigure, 'continuous');
            app.LeftKnob.Limits = [-5 5];
            app.LeftKnob.MajorTicks = [-5 -4 -3 -2 -1 0 1 2 3 4 5];
            app.LeftKnob.MajorTickLabels = {'-5', '-4', '-3', '-2', '-1', '0', '1', '2', '3', '4', '5'};
            app.LeftKnob.MinorTicks = 0;
            app.RightKnobLabel = uilabel(app.UIFigure);
            app.RightKnobLabel.Text = 'Right';
            app.RightKnob = uiknob(app.UIFigure, 'continuous');
            app.RightKnob.Limits = [-5 5];
            app.RightKnob.MajorTicks = [-5 -4 -3 -2 -1 0 1 2 3 4 5];
            app.RightKnob.MinorTicks = [];
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Map')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.Light_XEditFieldLabel = uilabel(app.UIFigure);
            app.Light_XEditFieldLabel.Text = 'Light_X';
            app.Light_XEditField = uieditfield(app.UIFigure, 'numeric');
            app.Light_YEditFieldLabel = uilabel(app.UIFigure);
            app.Light_YEditFieldLabel.Text = 'Light_Y';
            app.Light_YEditField = uieditfield(app.UIFigure, 'numeric');
            app.UpdateButton = uibutton(app.UIFigure, 'push');
            app.UpdateButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateButtonPushed, true);
            app.UpdateButton.Text = 'Update';
            app.X0EditFieldLabel = uilabel(app.UIFigure);
            app.X0EditFieldLabel.Text = 'X0';
            app.X0EditField = uieditfield(app.UIFigure, 'numeric');
            app.Y0EditFieldLabel = uilabel(app.UIFigure);
            app.Y0EditFieldLabel.Text = 'Y0';
            app.Y0EditField = uieditfield(app.UIFigure, 'numeric');
            app.Theta0EditFieldLabel = uilabel(app.UIFigure);
            app.Theta0EditFieldLabel.Text = 'Theta0';
            app.Theta0EditField = uieditfield(app.UIFigure, 'numeric');
            app.ANNSwitchLabel = uilabel(app.UIFigure);
            app.ANNSwitchLabel.Text = 'ANN';
            app.ANNSwitch = uiswitch(app.UIFigure, 'toggle');
            app.ANNSwitch.ValueChangedFcn = createCallbackFcn(app, @ANNSwitchValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end